classdef Aplicacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        AlturaFrenado         matlab.ui.control.Slider
        AlturaFInal           matlab.ui.control.Slider
        AlturaFinalSpinner    matlab.ui.control.Spinner
        AlturaFrenadoSpinner  matlab.ui.control.Spinner
        SimulacinLabel        matlab.ui.control.Label
        IngreseelvalorodeslizeelsliderparacambiarlosvaloresLabel  matlab.ui.control.Label
        ConstanteA            matlab.ui.control.Slider
        ConstanteASpinner     matlab.ui.control.Spinner
        AlturatotalLabel      matlab.ui.control.Label
        AlturadefrenadoLabel  matlab.ui.control.Label
        ResetButton           matlab.ui.control.Button
        ConstanteLabel        matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        AceleracionLabel      matlab.ui.control.Label
        VelocidadLabel        matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        PELIGROACELERACINPELIGROSALabel  matlab.ui.control.Label
        EstadoButton          matlab.ui.control.StateButton
        VelocidadCheckBox     matlab.ui.control.CheckBox
        AceleracinCheckBox    matlab.ui.control.CheckBox
        JerkCheckBox          matlab.ui.control.CheckBox
        AgainButton           matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Property % Description
        hf=69;
        hi=35;
        g=9.81;
        m=1000;
        A=1320;
    end
    
    methods (Access = private)
        
        function actualizar(app)
            %% Caida libre
            t=(2*(app.hf-app.hi)/app.g)^.5;%Tiempo de caida libre
            vc= @(t) app.g*t;%velocidad caida libre
            hc=@(t) app.hf-app.g*.5*t.^2;
            vo=(2*9.81*(app.hf-app.hi))^.5;%velocidad final(inicial del frenado)
            %% Calculo
            C_1 = vo - app.g*app.m/app.A;
            v = @(t) C_1*exp(-app.A*t/app.m)+app.g*app.m/app.A;
            a = @(t) (app.A/app.m)*C_1*exp(-app.A*t/app.m);
            j = @(t) (app.A^2/app.m^2)*C_1*exp(-app.A*t/app.m);            
            h = @(t) app.hi-app.m/app.A*(-C_1*exp(-app.A*t/app.m)+ app.g*t+C_1);
            
            %% Graficaciones
            %Caida libre
            ts=linspace(0,t);
            alturac=hc(ts);
            vc1=vc(ts);
            
            %Frenado                        
            tf=0;
            t=[tf];            
            salto=ts(2);
            while h(tf+salto)>0
                tf=tf+salto;
                t=[t tf];
            end
            altura=h(t);
            vf=v(t);
            af=a(t);
            jf=j(t);            
            
            alturas=[alturac altura];
            app.UIAxes.YLabel.String = "Altura"
            hold(app.UIAxes,"off")
            if app.EstadoButton.Value==0   
                set(app.AgainButton,"visible","off")
                plot(app.UIAxes,-3,-3)
                cadenalegend=[];
                if app.VelocidadCheckBox.Value==1
                    plot(app.UIAxes,[vc1 vf],alturas,'r')    
                    hold(app.UIAxes,"on")
                    cadenalegend=[cadenalegend "Velocidad"]
                end
                if app.AceleracinCheckBox.Value==1
                    plot(app.UIAxes,[ones(size(ts))*app.g af],[alturac altura],'b')
                    hold(app.UIAxes,"on")
                    cadenalegend=[cadenalegend "Aceleración"]
                end
                if app.JerkCheckBox.Value==1
                    plot(app.UIAxes,jf./app.g,altura,"g")
                    cadenalegend=[cadenalegend "Jerk"]                    
                end             
                legend(app.UIAxes,cadenalegend)
                app.UIAxes.XLabel.String = "Velocidad [m/s],aceleracion, jerk(g/s)"                
                axis(app.UIAxes,[0 max([af vf])+5 0 app.hf])  
            else
                set(app.AgainButton,"visible","on")
                axis(app.UIAxes,[0 10 0 app.hf])                  
                app.UIAxes.XLabel.String = ""
                for i=1: 4 :length(alturac)
                   pause(1/500)                   
                   plot(app.UIAxes,5,alturac(i),"or")
                   legend(app.UIAxes," ")
                end
                for i = 1 : 4 :length(altura)
                    pause(1/500)
                    plot(app.UIAxes,5,altura(i),"or")                    
                    legend(app.UIAxes," ")
                end                           
            end            
            
            app.AceleracionLabel.Text=string("Aceleracion maxima: "+string(max(a(t)))+"m/s^2");  
            app.VelocidadLabel.Text=string("Velocidad final: "+string(max(j(t)))+"m/s");  
            if max(a(t)) >= app.g*5
                set(app.PELIGROACELERACINPELIGROSALabel,"visible","on")
            else
                set(app.PELIGROACELERACINPELIGROSALabel,"visible","off")
            end
            %app.JerkEditField.Value = double(max(j(t)));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                actualizar(app)               
        end

        % Value changing function: AlturaFrenado
        function AlturaFrenadoValueChanging(app, event)
            changingValue = event.Value;
            app.hi = changingValue;
            actualizar(app)                         
            app.AlturaFrenadoSpinner.Value=changingValue;
        end

        % Value changing function: AlturaFInal
        function AlturaFInalValueChanging(app, event)
            changingValue = event.Value;
            app.hf = changingValue;
            actualizar(app)                    
            app.AlturaFinalSpinner.Value=changingValue;
        end

        % Callback function
        function XSpinnerValueChanging(app, event)
            changingValue = event.Value;                 
        end

        % Callback function
        function XSpinnerValueChanged(app, event)
            value = app.XSpinner.Value;
            app.cx=value;
            puntoElectrico(app);
            barras(app, "ninguno", 1)
        end

        % Callback function
        function YSpinnerValueChanged(app, event)
            value = app.YSpinner.Value;
            app.cy=value;
            puntoElectrico(app);
            barras(app, "ninguno", 1)
        end

        % Value changed function: AlturaFinalSpinner
        function AlturaFinalSpinnerValueChanged(app, event)
            value = app.AlturaFinalSpinner.Value;
            app.hf = value;
            actualizar(app)   
            app.AlturaFInal.Value=value;            
        end

        % Value changed function: AlturaFrenadoSpinner
        function AlturaFrenadoSpinnerValueChanged(app, event)
            value = app.AlturaFrenadoSpinner.Value;
            app.hi = value;
            actualizar(app)   
            app.AlturaFrenado.Value=value;
        end

        % Value changing function: ConstanteA
        function ConstanteAValueChanging(app, event)
            changingValue = event.Value;
            app.A = changingValue;
            actualizar(app)
            app.ConstanteASpinner.Value=changingValue;
        end

        % Value changed function: ConstanteASpinner
        function ConstanteASpinnerValueChanged(app, event)
            value = app.ConstanteASpinner.Value;
            app.A = value;
            actualizar(app)   
            app.ConstanteA.Value=value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.hf=69;
            app.hi=35;
            app.A=1320;
            app.AlturaFrenadoSpinner.Value=app.hi;
            app.AlturaFinalSpinner.Value=app.hf;
            app.AlturaFInal.Value=app.hf;  
            app.AlturaFrenado.Value=app.hi;
            app.ConstanteASpinner.Value=app.A;
            app.ConstanteA.Value=app.A;
            actualizar(app)
        end

        % Value changed function: EstadoButton
        function EstadoButtonValueChanged(app, event)
            value = app.EstadoButton.Value;
            actualizar(app)
        end

        % Value changed function: VelocidadCheckBox
        function VelocidadCheckBoxValueChanged(app, event)
            value = app.VelocidadCheckBox.Value;
            actualizar(app)
        end

        % Value changed function: AceleracinCheckBox
        function AceleracinCheckBoxValueChanged(app, event)
            value = app.AceleracinCheckBox.Value;
            actualizar(app)
        end

        % Value changed function: JerkCheckBox
        function JerkCheckBoxValueChanged(app, event)
            value = app.JerkCheckBox.Value;
            actualizar(app)
        end

        % Button pushed function: AgainButton
        function AgainButtonPushed(app, event)
            actualizar(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {562, 562};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {320, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AlturaFrenado = uislider(app.LeftPanel);
            app.AlturaFrenado.Limits = [15 49.9];
            app.AlturaFrenado.ValueChangingFcn = createCallbackFcn(app, @AlturaFrenadoValueChanging, true);
            app.AlturaFrenado.Value = 39;
            app.AlturaFInal = uislider(app.LeftPanel);
            app.AlturaFInal.Limits = [50 150];
            app.AlturaFInal.ValueChangingFcn = createCallbackFcn(app, @AlturaFInalValueChanging, true);
            app.AlturaFInal.Value = 69;
            app.AlturaFinalSpinner = uispinner(app.LeftPanel);
            app.AlturaFinalSpinner.Step = 0.05;
            app.AlturaFinalSpinner.Limits = [50 150];
            app.AlturaFinalSpinner.ValueChangedFcn = createCallbackFcn(app, @AlturaFinalSpinnerValueChanged, true);
            app.AlturaFinalSpinner.Value = 69;
            app.AlturaFrenadoSpinner = uispinner(app.LeftPanel);
            app.AlturaFrenadoSpinner.Step = 0.05;
            app.AlturaFrenadoSpinner.Limits = [15 49.9];
            app.AlturaFrenadoSpinner.ValueChangedFcn = createCallbackFcn(app, @AlturaFrenadoSpinnerValueChanged, true);
            app.AlturaFrenadoSpinner.Value = 39;
            app.SimulacinLabel = uilabel(app.LeftPanel);
            app.SimulacinLabel.Text = 'Simulación';
            app.IngreseelvalorodeslizeelsliderparacambiarlosvaloresLabel = uilabel(app.LeftPanel);
            app.IngreseelvalorodeslizeelsliderparacambiarlosvaloresLabel.Text = 'Ingrese el valor o deslize el slider para cambiar los valores';
            app.ConstanteA = uislider(app.LeftPanel);
            app.ConstanteA.Limits = [700 1700];
            app.ConstanteA.ValueChangingFcn = createCallbackFcn(app, @ConstanteAValueChanging, true);
            app.ConstanteA.Value = 1320;
            app.ConstanteASpinner = uispinner(app.LeftPanel);
            app.ConstanteASpinner.Step = 0.05;
            app.ConstanteASpinner.Limits = [700 1700];
            app.ConstanteASpinner.ValueChangedFcn = createCallbackFcn(app, @ConstanteASpinnerValueChanged, true);
            app.ConstanteASpinner.Value = 1320;
            app.AlturatotalLabel = uilabel(app.LeftPanel);
            app.AlturatotalLabel.Text = 'Altura total';
            app.AlturadefrenadoLabel = uilabel(app.LeftPanel);
            app.AlturadefrenadoLabel.Text = 'Altura de frenado';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ConstanteLabel = uilabel(app.LeftPanel);
            app.ConstanteLabel.Text = 'Constante';
            app.RightPanel = uipanel(app.GridLayout);
            app.AceleracionLabel = uilabel(app.RightPanel);
            app.AceleracionLabel.Text = 'm/s^2';
            app.VelocidadLabel = uilabel(app.RightPanel);
            app.VelocidadLabel.Text = 'm/s';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Frenado Magnetico')
            xlabel(app.UIAxes, 'm/s, m/s^2, g/s')
            ylabel(app.UIAxes, 'Altura')
            app.PELIGROACELERACINPELIGROSALabel = uilabel(app.RightPanel);
            app.PELIGROACELERACINPELIGROSALabel.Visible = 'off';
            app.PELIGROACELERACINPELIGROSALabel.Text = 'PELIGRO, ACELERACIÓN PELIGROSA';
            app.EstadoButton = uibutton(app.RightPanel, 'state');
            app.EstadoButton.ValueChangedFcn = createCallbackFcn(app, @EstadoButtonValueChanged, true);
            app.EstadoButton.Text = 'Grafica / Animacion';
            app.VelocidadCheckBox = uicheckbox(app.RightPanel);
            app.VelocidadCheckBox.ValueChangedFcn = createCallbackFcn(app, @VelocidadCheckBoxValueChanged, true);
            app.VelocidadCheckBox.Text = 'Velocidad';
            app.VelocidadCheckBox.Value = true;
            app.AceleracinCheckBox = uicheckbox(app.RightPanel);
            app.AceleracinCheckBox.ValueChangedFcn = createCallbackFcn(app, @AceleracinCheckBoxValueChanged, true);
            app.AceleracinCheckBox.Text = 'Aceleración';
            app.AceleracinCheckBox.Value = true;
            app.JerkCheckBox = uicheckbox(app.RightPanel);
            app.JerkCheckBox.ValueChangedFcn = createCallbackFcn(app, @JerkCheckBoxValueChanged, true);
            app.JerkCheckBox.Text = 'Jerk';
            app.JerkCheckBox.Value = true;
            app.AgainButton = uibutton(app.RightPanel, 'push');
            app.AgainButton.ButtonPushedFcn = createCallbackFcn(app, @AgainButtonPushed, true);
            app.AgainButton.Visible = 'off';
            app.AgainButton.Text = {'Again'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aplicacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end